classdef challengeTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChemtechComradesCheckBox        matlab.ui.control.CheckBox
        InhumanScreechingSoundsCheckBox  matlab.ui.control.CheckBox
        PeakPerformanceCheckBox         matlab.ui.control.CheckBox
        TheSunDiscNeverSetsCheckBox     matlab.ui.control.CheckBox
        SpookyScarySkeletonsCheckBox    matlab.ui.control.CheckBox
        CalculatedCheckBox              matlab.ui.control.CheckBox
        StrengthAboveAllCheckBox        matlab.ui.control.CheckBox
        ElementalMyDearWatsonCheckBox   matlab.ui.control.CheckBox
        EverybodywasWujuFightingCheckBox  matlab.ui.control.CheckBox
        IceIceBabyCheckBox              matlab.ui.control.CheckBox
        ForDemaciaCheckBox              matlab.ui.control.CheckBox
        AllHandsonDeckCheckBox          matlab.ui.control.CheckBox
        InvulnerableCheckBox            matlab.ui.control.CheckBox
        InspiringCheckBox               matlab.ui.control.CheckBox
        IngeniousCheckBox               matlab.ui.control.CheckBox
        IncandescentCheckBox            matlab.ui.control.CheckBox
        IndomitableCheckBox             matlab.ui.control.CheckBox
        InvisibleCheckBox               matlab.ui.control.CheckBox
        SpiceofLifeCheckBox             matlab.ui.control.CheckBox
        JackofAllChampsCheckBox         matlab.ui.control.CheckBox
        FiveUnderFiveCheckBox           matlab.ui.control.CheckBox
        RegionsofRuneterraLabel         matlab.ui.control.Label
        ImHelpingCheckBox               matlab.ui.control.CheckBox
        ItsaTrapCheckBox                matlab.ui.control.CheckBox
        GetOverHereCheckBox             matlab.ui.control.CheckBox
        SummonersontheRiftCheckBox      matlab.ui.control.CheckBox
        WereGoodOverHereCheckBox        matlab.ui.control.CheckBox
        WheredTheyGoCheckBox            matlab.ui.control.CheckBox
        TheyJustDontDieCheckBox         matlab.ui.control.CheckBox
        WeProtecCheckBox                matlab.ui.control.CheckBox
        ItHasUltimateIntheNameCheckBox  matlab.ui.control.CheckBox
        NowhereToHideCheckBox           matlab.ui.control.CheckBox
        OtherconditionalChallengesLabel  matlab.ui.control.Label
        RekindleTheOldFurnaceCheckBox   matlab.ui.control.CheckBox
        WellRoundedTravelerCheckBox     matlab.ui.control.CheckBox
        MasterTheEnemyCheckBox          matlab.ui.control.CheckBox
        MasterYourselfCheckBox          matlab.ui.control.CheckBox
        NeedABiggerClosetCheckBox       matlab.ui.control.CheckBox
        PositionFilterDropDown          matlab.ui.control.DropDown
        PositionFilterDropDownLabel     matlab.ui.control.Label
        ChallengeDropDown               matlab.ui.control.DropDown
        ChallengeDropDownLabel          matlab.ui.control.Label
        ChampionNameDropDown            matlab.ui.control.DropDown
        ChampionDropDownLabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Button                          matlab.ui.control.Button
        MarksmanSpecialistCheckBox      matlab.ui.control.CheckBox
        MasterMarksmanCheckBox          matlab.ui.control.CheckBox
        SupportSpecialistCheckBox       matlab.ui.control.CheckBox
        TankSpecialistCheckBox          matlab.ui.control.CheckBox
        AssassinSpecialistCheckBox      matlab.ui.control.CheckBox
        MageSpecialistCheckBox          matlab.ui.control.CheckBox
        FighterSpecialistCheckBox       matlab.ui.control.CheckBox
        MasterSupportCheckBox           matlab.ui.control.CheckBox
        MasterTankCheckBox              matlab.ui.control.CheckBox
        MasterAssassinCheckBox          matlab.ui.control.CheckBox
        ChallengesuniquetospecificrolesLabel  matlab.ui.control.Label
        MasterMageCheckBox              matlab.ui.control.CheckBox
        MasterFighterCheckBox           matlab.ui.control.CheckBox
        RoleLabel                       matlab.ui.control.Label
        Role                            matlab.ui.control.Label
        AllRandomAllChampionsCheckBox   matlab.ui.control.CheckBox
        HoldThatPoseCheckBox            matlab.ui.control.CheckBox
        InvincibleCheckBox              matlab.ui.control.CheckBox
        SamePentaDifferentChampCheckBox  matlab.ui.control.CheckBox
        PerfectionistCheckBox           matlab.ui.control.CheckBox
        ProteanOverrideCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        champList % List of champions as strings
        classObjects % Cell array of class objects
        challengeList % List of challenge properties in champ class objects
        challengeFullNames % full names of the challenges
    end
    
    % Helper and initialization methods
    methods (Access = private)
        
        function checkBoxChangedHelper(app,callbackName,propertyName)
            id = strcmp(app.champList,app.ChampionNameDropDown.Value);
            app.classObjects{id}.(propertyName) = app.(callbackName).Value;
            classObjs = app.classObjects;
            save("challengeData.mat","classObjs");
        end
        
        % Initialize classes
        function initClasses(app)
            app.classObjects = cell(size(app.champList));
            for i = 1:numel(app.champList)
                champ = app.champList(i);
                eval(champ + "Obj = " + champ + ";")
                app.classObjects{i} = eval(champ + "Obj;");
            end
        end
        
        function createChampList(app)
            app.champList = ["Aatrox"; "Ahri"; "Akali"; "Akshan";
                 "Alistar"; "Ambessa"; "Amumu"; "Anivia"; "Annie";
                 "Aphelios"; "Ashe"; "AurelionSol"; "Aurora"; "Azir";
                 "Bard"; "BelVeth"; "Blitzcrank"; "Brand"; "Braum";
                 "Briar"; "Caitlyn"; "Camille"; "Cassiopeia"; "ChoGath";
                 "Corki"; "Darius"; "Diana"; "Draven"; "DrMundo"; "Ekko";
                 "Elise"; "Evelynn"; "Ezreal"; "Fiddlesticks"; "Fiora";
                 "Fizz"; "Galio"; "Gangplank"; "Garen"; "Gnar"; "Gragas";
                 "Graves"; "Gwen"; "Hecarim"; "Heimerdinger"; "Hwei";
                 "Illaoi"; "Irelia"; "Ivern"; "Janna"; "JarvanIV"; "Jax";
                 "Jayce"; "Jhin"; "Jinx"; "KSante"; "KaiSa"; "Kalista";
                 "Karma"; "Karthus"; "Kassadin"; "Katarina"; "Kayle";
                 "Kayn"; "Kennen"; "KhaZix"; "Kindred"; "Kled"; "KogMaw";
                 "LeBlanc"; "LeeSin"; "Leona"; "Lillia"; "Lissandra";
                 "Lucian"; "Lulu"; "Lux"; "Malphite"; "Malzahar";
                 "Maokai"; "MasterYi"; "Mel"; "Milio"; "MissFortune";
                 "Mordekaiser"; "Morgana"; "Naafiri"; "Nami"; "Nasus";
                 "Nautilus"; "Neeko"; "Nidalee"; "Nilah"; "Nocturne";
                 "Nunu"; "Olaf"; "Orianna"; "Ornn"; "Pantheon"; "Poppy";
                 "Pyke"; "Qiyana"; "Quinn"; "Rakan"; "Rammus"; "RekSai";
                 "Rell"; "RenataGlasc"; "Renekton"; "Rengar"; "Riven";
                 "Rumble"; "Ryze"; "Samira"; "Sejuani"; "Senna";
                 "Seraphine"; "Sett"; "Shaco"; "Shen"; "Shyvana";
                 "Singed"; "Sion"; "Sivir"; "Skarner"; "Smolder"; "Sona";
                 "Soraka"; "Swain"; "Sylas"; "Syndra"; "TahmKench";
                 "Taliyah"; "Talon"; "Taric"; "Teemo"; "Thresh";
                 "Tristana"; "Trundle"; "Tryndamere"; "TwistedFate";
                 "Twitch"; "Udyr"; "Urgot"; "Varus"; "Vayne"; "Veigar";
                 "VelKoz"; "Vex"; "Vi"; "Viego"; "Viktor"; "Vladimir";
                 "Volibear"; "Warwick"; "Wukong"; "Xayah"; "Xerath";
                 "XinZhao"; "Yasuo"; "Yone"; "Yorick"; "Yuumi"; "Zac";
                 "Zed"; "Zeri"; "Ziggs"; "Zilean"; "Zoe"; "Zyra";];
        end
        
        function showHideRoleChallenges(app,id)
            roles = ["fighter"; "assassin"; "marksman"; 
                     "tank";    "mage";      "support"];
            master = ["MasterFighterCheckBox";  "MasterAssassinCheckBox";
                      "MasterMarksmanCheckBox"; "MasterTankCheckBox";
                      "MasterMageCheckBox"; "MasterSupportCheckBox"];
            specialist = ["FighterSpecialistCheckBox"; 
                          "AssassinSpecialistCheckBox";
                          "MarksmanSpecialistCheckBox";
                          "TankSpecialistCheckBox";
                          "MageSpecialistCheckBox";
                          "SupportSpecialistCheckBox"];
            obtain = ["IndomitableCheckBox";
                      "InvisibleCheckBox";
                      "IngeniousCheckBox";
                      "InvulnerableCheckBox";
                      "IncandescentCheckBox";
                      "InspiringCheckBox"];
            masterProps = ["masterFighter"; "masterAssassin"; "masterMarksman";
                           "masterTank";    "masterMage";     "masterSupport"];
            specialistProps = ["fighterSpecialist";  "assassinSpecialist";
                               "marksmanSpecialist"; "tankSpecialist";
                               "mageSpecialist";     "supportSpecialist"];
            obtainProps = ["indomitable"; "invisible"; "ingenious";
                           "invulnerable"; "incandescent"; "inspiring"];
            for i = 1:numel(roles)
                if find(strcmp(app.classObjects{id}.role,roles(i)))
                    app.(master(i)).Visible = true;
                    app.(master(i)).Value = app.classObjects{id}.(masterProps(i));
                    app.(specialist(i)).Visible = true;
                    app.(specialist(i)).Value = app.classObjects{id}.(specialistProps(i));
                    app.(obtain(i)).Visible = true;
                    app.(obtain(i)).Value = app.classObjects{id}.(obtainProps(i));
                else
                    app.(master(i)).Visible = false;
                    app.(specialist(i)).Visible = false;
                    app.(obtain(i)).Visible = false;
                end
            end
        end
        
        function createChallengeList(app)
            app.challengeList = ["protean"
                                 "perfectionist"
                                 "penta"
                                 "invincible"
                                 "allRandomAllChampions"
                                 "biggerCloset"
                                 "masterYourself"
                                 "masterTheEnemy"
                                 "wellRoundedTraveler"
                                 "rekindleOldFurnace"
                                 "jackOfAllChamps"
                                 "nowhereToHide"
                                 "ultimateInName"
                                 "weProtec"
                                 "justDontDie"
                                 "wheredTheyGo"
                                 "wereGoodOverHere"
                                 "summonersOnTheRift"
                                 "getOverHere"
                                 "itsATrap"
                                 "imHelping"
                                 "holdPose"
                                 "masterFighter"
                                 "masterAssassin"
                                 "masterMarksman"
                                 "masterTank"
                                 "masterMage"
                                 "masterSupport"
                                 "fighterSpecialist"
                                 "assassinSpecialist"
                                 "marksmanSpecialist"
                                 "tankSpecialist"
                                 "mageSpecialist"
                                 "supportSpecialist"
                                 "indomitable"
                                 "invisible"
                                 "ingenious"
                                 "invulnerable"
                                 "incandescent"
                                 "inspiring"
                                 "fiveUnderFive"
                                 "allHandsOnDeck"
                                 "forDemacia"
                                 "iceIceBaby"
                                 "wujuFighting"
                                 "dearWatson"
                                 "strengthAboveAll"
                                 "calculated"
                                 "spookyScarySkeletons"
                                 "theSunDiscNeverSets"
                                 "peakPerformance"
                                 "inhumanScreechingSounds"
                                 "chemtechComrades"];
            app.challengeFullNames = ["Protean Override"
                                      "Perfectionist"
                                      "Same Penta, Different Champ"
                                      "Invincible"
                                      "All Random All Champions"
                                      "Need A Bigger Closet"
                                      "Master Yourself"
                                      "Master The Enemy"
                                      "Well-Rounded Traveler"
                                      "Rekindle The Old Furnace"
                                      "Jack of All Champs"
                                      "Nowhere To Hide"
                                      "It Has Ultimate In The Name"
                                      "We Protec"
                                      "They Just Don't Die"
                                      "Where'd They Go?"
                                      "We're Good Over Here"
                                      "Summoners on the Rift"
                                      "Get Over Here"
                                      "It's a Trap!"
                                      "I'm Helping"
                                      "Hold That Pose"
                                      "Master Fighter"
                                      "Master Assassin"
                                      "Master Marksman"
                                      "Master Tank"
                                      "Master Mage"
                                      "Master Support"
                                      "Fighter Specialist"
                                      "Assassin Specialist"
                                      "Marksman Specialist"
                                      "Tank Specialist"
                                      "Mage Specialist"
                                      "Support Specialist"
                                      "Indomitable"
                                      "Invisible"
                                      "Ingenious"
                                      "Invulnerable"
                                      "Incandescent"
                                      "Inspiring"
                                      "5 Under 5'"
                                      "All Hands On Deck"
                                      "FOR DEMACIA"
                                      "Ice, Ice, Baby"
                                      "Everybody was Wuju Fighting"
                                      "Elemental, My Dear Watson"
                                      "Strength Above All"
                                      "Calculated"
                                      "Spooky Scary Skeletons"
                                      "The Sun Disc NMever Sets"
                                      "Peak Performance"
                                      "(Inhuman Screeching Sounds)"
                                      "Chemtech Comrades"];
        end
        
        function showHideConditionalChallenges(app,id)
            
            conditionalChallenges = ...
                             ["nowhereToHide"
                              "ultimateInName"
                              "weProtec"
                              "justDontDie"
                              "wheredTheyGo"
                              "wereGoodOverHere"
                              "summonersOnTheRift"
                              "getOverHere"
                              "itsATrap"
                              "imHelping"
                              "holdPose"];
            conditionalCheckBoxName = [...
                            "NowhereToHideCheckBox"
                            "ItHasUltimateIntheNameCheckBox"
                            "WeProtecCheckBox"
                            "TheyJustDontDieCheckBox"
                            "WheredTheyGoCheckBox"
                            "WereGoodOverHereCheckBox"
                            "SummonersontheRiftCheckBox"
                            "GetOverHereCheckBox"
                            "ItsaTrapCheckBox"
                            "ImHelpingCheckBox"
                            "HoldThatPoseCheckBox"];
            for i = 1:numel(conditionalChallenges)
                if isprop(app.classObjects{id},conditionalChallenges(i))
                    app.(conditionalCheckBoxName(i)).Visible = true;
                    app.(conditionalCheckBoxName(i)).Value = app.classObjects{id}.(conditionalChallenges(i));
                else
                    app.(conditionalCheckBoxName(i)).Visible = false;
                end
            end
        end
        
        function showHideRegionalChallenges(app,id)
            regionChallenges = ["fiveUnderFive"
                                "allHandsOnDeck"
                                "forDemacia"
                                "iceIceBaby"
                                "wujuFighting"
                                "dearWatson"
                                "strengthAboveAll"
                                "calculated"
                                "spookyScarySkeletons"
                                "theSunDiscNeverSets"
                                "peakPerformance"
                                "inhumanScreechingSounds"
                                "chemtechComrades"];
            regionCheckBoxName = ["FiveUnderFiveCheckBox"
                                  "AllHandsonDeckCheckBox"
                                  "ForDemaciaCheckBox"
                                  "IceIceBabyCheckBox"
                                  "EverybodywasWujuFightingCheckBox"
                                  "ElementalMyDearWatsonCheckBox"
                                  "StrengthAboveAllCheckBox"
                                  "CalculatedCheckBox"
                                  "SpookyScarySkeletonsCheckBox"
                                  "TheSunDiscNeverSetsCheckBox"
                                  "PeakPerformanceCheckBox"
                                  "InhumanScreechingSoundsCheckBox"
                                  "ChemtechComradesCheckBox"];
            for i = 1:numel(regionChallenges)
                if isprop(app.classObjects{id},regionChallenges(i))
                    app.(regionCheckBoxName(i)).Visible = true;
                    app.(regionCheckBoxName(i)).Value = app.classObjects{id}.(regionChallenges(i));
                else
                    app.(regionCheckBoxName(i)).Visible = false;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("challenges","challenges/Champion","challenges/Role")
            warning('off','MATLAB:load:variableNotFound');
            createChallengeList(app);
            createChampList(app)
            if ~exist("challengeData.mat","file")
                % If the mat file does not exist, create it as an empty
                % file. It will be filled in as user selects champions in
                % the app.
                initClasses(app);
                classObjs = app.classObjects;
                save("challengeData.mat","classObjs");
            else
                load("challengeData.mat","classObjs");
                app.classObjects = classObjs;
            end
            % Calling this method to populate the values of the check
            % boxes.
            ChampionNameDropDownValueChanged(app);

            % Populate values of filtering dropdown
            app.ChallengeDropDown.Items = app.challengeFullNames;
            app.ChallengeDropDown.ItemsData = (1:numel(app.challengeFullNames));
        end

        % Value changed function: ChampionNameDropDown
        function ChampionNameDropDownValueChanged(app, event)
            % id is the index location of the chosen champion in the array
            id = strcmp(app.champList,app.ChampionNameDropDown.Value);
            
            % Populate values in the app
            app.Role.Text = app.classObjects{id}.role;
            app.ProteanOverrideCheckBox.Value = app.classObjects{id}.protean;
            app.PerfectionistCheckBox.Value = app.classObjects{id}.perfectionist;
            app.SamePentaDifferentChampCheckBox.Value = app.classObjects{id}.penta;
            app.InvincibleCheckBox.Value = app.classObjects{id}.invincible;
            app.AllRandomAllChampionsCheckBox.Value = app.classObjects{id}.allRandomAllChampions;
            app.NeedABiggerClosetCheckBox.Value = app.classObjects{id}.biggerCloset;
            app.MasterYourselfCheckBox.Value = app.classObjects{id}.masterYourself;
            app.MasterTheEnemyCheckBox.Value = app.classObjects{id}.masterTheEnemy;
            app.WellRoundedTravelerCheckBox.Value = app.classObjects{id}.wellRoundedTraveler;
            app.RekindleTheOldFurnaceCheckBox.Value = app.classObjects{id}.rekindleOldFurnace;
            app.JackofAllChampsCheckBox.Value = app.classObjects{id}.jackOfAllChamps;
            app.SpiceofLifeCheckBox.Value = app.classObjects{id}.spiceOfLife;
            
            % Call helper function to show or hide role challenges when a
            % different champion is selected.
            showHideRoleChallenges(app,id);
            showHideConditionalChallenges(app,id);
            showHideRegionalChallenges(app,id);

        end

        % Value changed function: ProteanOverrideCheckBox
        function ProteanOverrideCheckBoxValueChanged(app, event)
            cbName = "ProteanOverrideCheckBox";
            propName = "protean";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % turn warning back on
            warning('on','MATLAB:load:variableNotFound')
            % clean up path changes
            rmpath("challenges","challenges/Champion","challenges/Role")
        end

        % Value changed function: PerfectionistCheckBox
        function PerfectionistCheckBoxValueChanged(app, event)
            cbName = "PerfectionistCheckBox";
            propName = "perfectionist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: SamePentaDifferentChampCheckBox
        function SamePentaDifferentChampCheckBoxValueChanged(app, event)
            cbName = "SamePentaDifferentChampCheckBox";
            propName = "penta";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: InvincibleCheckBox
        function InvincibleCheckBoxValueChanged(app, event)
            cbName = "InvincibleCheckBox";
            propName = "invincible";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: AllRandomAllChampionsCheckBox
        function AllRandomAllChampionsCheckBoxValueChanged(app, event)
            cbName = "AllRandomAllChampionsCheckBox";
            propName = "allRandomAllChampions";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterFighterCheckBox
        function MasterFighterCheckBoxValueChanged(app, event)
            cbName = "MasterFighterCheckBox";
            propName = "masterFighter";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterMageCheckBox
        function MasterMageCheckBoxValueChanged(app, event)
            cbName = "MasterMageCheckBox";
            propName = "masterMage";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterAssassinCheckBox
        function MasterAssassinCheckBoxValueChanged(app, event)
            cbName = "MasterAssassinCheckBox";
            propName = "masterAssassin";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterTankCheckBox
        function MasterTankCheckBoxValueChanged(app, event)
            cbName = "MasterTankCheckBox";
            propName = "masterTank";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterSupportCheckBox
        function MasterSupportCheckBoxValueChanged(app, event)
            cbName = "MasterSupportCheckBox";
            propName = "masterSupport";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: FighterSpecialistCheckBox
        function FighterSpecialistCheckBoxValueChanged(app, event)
            cbName = "FighterSpecialistCheckBox";
            propName = "fighterSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MageSpecialistCheckBox
        function MageSpecialistCheckBoxValueChanged(app, event)
            cbName = "MageSpecialistCheckBox";
            propName = "mageSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: AssassinSpecialistCheckBox
        function AssassinSpecialistCheckBoxValueChanged(app, event)
            cbName = "AssassinSpecialistCheckBox";
            propName = "assassinSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: TankSpecialistCheckBox
        function TankSpecialistCheckBoxValueChanged(app, event)
            cbName = "TankSpecialistCheckBox";
            propName = "tankSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: SupportSpecialistCheckBox
        function SupportSpecialistCheckBoxValueChanged(app, event)
            cbName = "SupportSpecialistCheckBox";
            propName = "supportSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterMarksmanCheckBox
        function MasterMarksmanCheckBoxValueChanged(app, event)
            cbName = "MasterMarksmanCheckBox";
            propName = "masterMarksman";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MarksmanSpecialistCheckBox
        function MarksmanSpecialistCheckBoxValueChanged(app, event)
            cbName = "MarksmanSpecialistCheckBox";
            propName = "marksmanSpecialist";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: HoldThatPoseCheckBox
        function HoldThatPoseCheckBoxValueChanged(app, event)
            cbName = "HoldThatPoseCheckBox";
            propName = "holdPose";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % When the button is pushed, generate a list of champions that
            % are eligible for the selected challenge but do not have
            % progress.
            id = app.ChallengeDropDown.Value;
            challenge = app.challengeList(id);
            app.UITable.Visible = true;
            checkProperties = zeros(length(app.champList),1);
            for i = 1:numel(app.champList)
                checkProperties(i) = isprop(app.classObjects{i},challenge);
                if checkProperties(i) && app.classObjects{i}.(challenge)
                    % Only show champions that are missing progress
                    checkProperties(i) = false;
                end
                if isempty(app.PositionFilterDropDown.Value) || ...
                        str2double(app.PositionFilterDropDown.Value) == 1
                    % Show all champions if nothing is specified
                elseif ~strcmp(app.classObjects{i}.lane,...
                        app.PositionFilterDropDown.Items{...
                        str2double(app.PositionFilterDropDown.Value)})
                    % If a champion's lane does NOT match the specified
                    % value, don't show it
                    checkProperties(i) = false;
                end
            end
            if isempty(app.PositionFilterDropDown.Value) || ...
                        str2double(app.PositionFilterDropDown.Value) == 1
                %
                roleNeeds = "All champs needing: ";
            else
                roleNeeds = app.PositionFilterDropDown.Items{...
                        str2double(app.PositionFilterDropDown.Value)}...
                        + " champs needing: ";
            end
            app.UITable.Data = app.champList(logical(checkProperties));
            app.UITable.ColumnName = roleNeeds + ...
                app.ChallengeDropDown.Items{app.ChallengeDropDown.Value};
        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            % displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            % 
        end

        % Value changed function: NeedABiggerClosetCheckBox
        function NeedABiggerClosetCheckBoxValueChanged(app, event)
            cbName = "NeedABiggerClosetCheckBox";
            propName = "biggerCloset";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterYourselfCheckBox
        function MasterYourselfCheckBoxValueChanged(app, event)
            cbName = "MasterYourselfCheckBox";
            propName = "masterYourself";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: MasterTheEnemyCheckBox
        function MasterTheEnemyCheckBoxValueChanged(app, event)
            cbName = "MasterTheEnemyCheckBox";
            propName = "masterTheEnemy";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: WellRoundedTravelerCheckBox
        function WellRoundedTravelerCheckBoxValueChanged(app, event)
            cbName = "WellRoundedTravelerCheckBox";
            propName = "wellRoundedTraveler";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: RekindleTheOldFurnaceCheckBox
        function RekindleTheOldFurnaceCheckBoxValueChanged(app, event)
            cbName = "RekindleTheOldFurnaceCheckBox";
            propName = "rekindleOldFurnace";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: NowhereToHideCheckBox
        function NowhereToHideCheckBoxValueChanged(app, event)
            cbName = "NowhereToHideCheckBox";
            propName = "nowhereToHide";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: ItHasUltimateIntheNameCheckBox
        function ItHasUltimateIntheNameCheckBoxValueChanged(app, event)
            cbName = "ItHasUltimateIntheNameCheckBox";
            propName = "ultimateInName";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: WeProtecCheckBox
        function WeProtecCheckBoxValueChanged(app, event)
            cbName = "WeProtecCheckBox";
            propName = "weProtec";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: TheyJustDontDieCheckBox
        function TheyJustDontDieCheckBoxValueChanged(app, event)
            cbName = "TheyJustDontDieCheckBox";
            propName = "justDontDie";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: WheredTheyGoCheckBox
        function WheredTheyGoCheckBoxValueChanged(app, event)
            cbName = "WheredTheyGoCheckBox";
            propName = "wheredTheyGo";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: WereGoodOverHereCheckBox
        function WereGoodOverHereCheckBoxValueChanged(app, event)
            cbName = "WereGoodOverHereCheckBox";
            propName = "wereGoodOverHere";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: SummonersontheRiftCheckBox
        function SummonersontheRiftCheckBoxValueChanged(app, event)
            cbName = "SummonersontheRiftCheckBox";
            propName = "summonersOnTheRift";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: GetOverHereCheckBox
        function GetOverHereCheckBoxValueChanged(app, event)
            cbName = "GetOverHereCheckBox";
            propName = "getOverHere";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: ItsaTrapCheckBox
        function ItsaTrapCheckBoxValueChanged(app, event)
            cbName = "ItsaTrapCheckBox";
            propName = "itsATrap";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: ImHelpingCheckBox
        function ImHelpingCheckBoxValueChanged(app, event)
            cbName = "ImHelpingCheckBox";
            propName = "imHelping";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: JackofAllChampsCheckBox
        function JackofAllChampsCheckBoxValueChanged(app, event)
            cbName = "JackofAllChampsCheckBox";
            propName = "jackOfAllChamps";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: SpiceofLifeCheckBox
        function SpiceofLifeCheckBoxValueChanged(app, event)
            cbName = "SpiceofLifeCheckBox";
            propName = "spiceOfLife";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: IndomitableCheckBox
        function IndomitableCheckBoxValueChanged(app, event)
            cbName = "IndomitableCheckBox";
            propName = "indomitable";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: IncandescentCheckBox
        function IncandescentCheckBoxValueChanged(app, event)
            cbName = "IncandescentCheckBox";
            propName = "incandescent";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: IngeniousCheckBox
        function IngeniousCheckBoxValueChanged(app, event)
            cbName = "IngeniousCheckBox";
            propName = "ingenious";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: InvisibleCheckBox
        function InvisibleCheckBoxValueChanged(app, event)
            cbName = "InvisibleCheckBox";
            propName = "invisible";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: InspiringCheckBox
        function InspiringCheckBoxValueChanged(app, event)
            cbName = "InspiringCheckBox";
            propName = "inspiring";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: InvulnerableCheckBox
        function InvulnerableCheckBoxValueChanged(app, event)
            cbName = "InvulnerableCheckBox";
            propName = "invulnerable";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: FiveUnderFiveCheckBox
        function FiveUnderFiveCheckBoxValueChanged(app, event)
            cbName = "FiveUnderFiveCheckBox";
            propName = "fiveUnderFive";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: AllHandsonDeckCheckBox
        function AllHandsonDeckCheckBoxValueChanged(app, event)
            cbName = "AllHandsonDeckCheckBox";
            propName = "allHandsOnDeck";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: ForDemaciaCheckBox
        function ForDemaciaCheckBoxValueChanged(app, event)
            cbName = "ForDemaciaCheckBox";
            propName = "forDemacia";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: IceIceBabyCheckBox
        function IceIceBabyCheckBoxValueChanged(app, event)
            cbName = "IceIceBabyCheckBox";
            propName = "iceIceBaby";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: EverybodywasWujuFightingCheckBox
        function EverybodywasWujuFightingCheckBoxValueChanged(app, event)
            cbName = "EverybodywasWujuFightingCheckBox";
            propName = "wujuFighting";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: ElementalMyDearWatsonCheckBox
        function ElementalMyDearWatsonCheckBoxValueChanged(app, event)
            cbName = "ElementalMyDearWatsonCheckBox";
            propName = "dearWatson";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: StrengthAboveAllCheckBox
        function StrengthAboveAllCheckBoxValueChanged(app, event)
            cbName = "StrengthAboveAllCheckBox";
            propName = "strengthAboveAll";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: CalculatedCheckBox
        function CalculatedCheckBoxValueChanged(app, event)
            cbName = "CalculatedCheckBox";
            propName = "calculated";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: SpookyScarySkeletonsCheckBox
        function SpookyScarySkeletonsCheckBoxValueChanged(app, event)
            cbName = "SpookyScarySkeletonsCheckBox";
            propName = "spookyScarySkeletons";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: TheSunDiscNeverSetsCheckBox
        function TheSunDiscNeverSetsCheckBoxValueChanged(app, event)
            cbName = "TheSunDiscNeverSetsCheckBox";
            propName = "theSunDiscNeverSets";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: PeakPerformanceCheckBox
        function PeakPerformanceCheckBoxValueChanged(app, event)
            cbName = "PeakPerformanceCheckBox";
            propName = "peakPerformance";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: InhumanScreechingSoundsCheckBox
        function InhumanScreechingSoundsCheckBoxValueChanged(app, event)
            cbName = "InhumanScreechingSoundsCheckBox";
            propName = "inhumanScreechingSounds";
            checkBoxChangedHelper(app,cbName,propName);
        end

        % Value changed function: ChemtechComradesCheckBox
        function ChemtechComradesCheckBoxValueChanged(app, event)
            cbName = "ChemtechComradesCheckBox";
            propName = "chemtechComrades";
            checkBoxChangedHelper(app,cbName,propName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProteanOverrideCheckBox = uicheckbox(app.UIFigure);
            app.ProteanOverrideCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProteanOverrideCheckBoxValueChanged, true);
            app.ProteanOverrideCheckBox.Tooltip = {'Win Co-op vs. AI games with different champions'};
            app.ProteanOverrideCheckBox.Text = 'Protean Override';
            app.PerfectionistCheckBox = uicheckbox(app.UIFigure);
            app.PerfectionistCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerfectionistCheckBoxValueChanged, true);
            app.PerfectionistCheckBox.Tooltip = {'Earn S+ grades with different champions'};
            app.PerfectionistCheckBox.Text = 'Perfectionist';
            app.SamePentaDifferentChampCheckBox = uicheckbox(app.UIFigure);
            app.SamePentaDifferentChampCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamePentaDifferentChampCheckBoxValueChanged, true);
            app.SamePentaDifferentChampCheckBox.Tooltip = {'Get a pentakill with different champions'};
            app.SamePentaDifferentChampCheckBox.Text = 'Same Penta, Different Champ';
            app.InvincibleCheckBox = uicheckbox(app.UIFigure);
            app.InvincibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvincibleCheckBoxValueChanged, true);
            app.InvincibleCheckBox.Tooltip = {'Win without dying with different champions'};
            app.InvincibleCheckBox.Text = 'Invincible';
            app.HoldThatPoseCheckBox = uicheckbox(app.UIFigure);
            app.HoldThatPoseCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldThatPoseCheckBoxValueChanged, true);
            app.HoldThatPoseCheckBox.Tooltip = {'Win with a 5-stack with 3+ champs with 2+ immobilizing spells'};
            app.HoldThatPoseCheckBox.Text = 'Hold That Pose';
            app.AllRandomAllChampionsCheckBox = uicheckbox(app.UIFigure);
            app.AllRandomAllChampionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllRandomAllChampionsCheckBoxValueChanged, true);
            app.AllRandomAllChampionsCheckBox.Tooltip = {'Earn S- grades or higher on different champions in ARAM'};
            app.AllRandomAllChampionsCheckBox.Text = 'All Random All Champions';
            app.Role = uilabel(app.UIFigure);
            app.Role.Text = '';
            app.RoleLabel = uilabel(app.UIFigure);
            app.RoleLabel.Text = 'Role:';
            app.MasterFighterCheckBox = uicheckbox(app.UIFigure);
            app.MasterFighterCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterFighterCheckBoxValueChanged, true);
            app.MasterFighterCheckBox.Visible = 'off';
            app.MasterFighterCheckBox.Text = 'Master Fighter';
            app.MasterMageCheckBox = uicheckbox(app.UIFigure);
            app.MasterMageCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterMageCheckBoxValueChanged, true);
            app.MasterMageCheckBox.Visible = 'off';
            app.MasterMageCheckBox.Text = 'Master Mage';
            app.ChallengesuniquetospecificrolesLabel = uilabel(app.UIFigure);
            app.ChallengesuniquetospecificrolesLabel.Text = 'Challenges unique to specific roles:';
            app.MasterAssassinCheckBox = uicheckbox(app.UIFigure);
            app.MasterAssassinCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterAssassinCheckBoxValueChanged, true);
            app.MasterAssassinCheckBox.Visible = 'off';
            app.MasterAssassinCheckBox.Text = 'Master Assassin';
            app.MasterTankCheckBox = uicheckbox(app.UIFigure);
            app.MasterTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterTankCheckBoxValueChanged, true);
            app.MasterTankCheckBox.Visible = 'off';
            app.MasterTankCheckBox.Text = 'Master Tank';
            app.MasterSupportCheckBox = uicheckbox(app.UIFigure);
            app.MasterSupportCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterSupportCheckBoxValueChanged, true);
            app.MasterSupportCheckBox.Visible = 'off';
            app.MasterSupportCheckBox.Text = 'Master Support';
            app.FighterSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.FighterSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @FighterSpecialistCheckBoxValueChanged, true);
            app.FighterSpecialistCheckBox.Visible = 'off';
            app.FighterSpecialistCheckBox.Text = 'Fighter Specialist';
            app.MageSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.MageSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @MageSpecialistCheckBoxValueChanged, true);
            app.MageSpecialistCheckBox.Visible = 'off';
            app.MageSpecialistCheckBox.Text = 'Mage Specialist';
            app.AssassinSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.AssassinSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @AssassinSpecialistCheckBoxValueChanged, true);
            app.AssassinSpecialistCheckBox.Visible = 'off';
            app.AssassinSpecialistCheckBox.Text = 'Assassin Specialist';
            app.TankSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.TankSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @TankSpecialistCheckBoxValueChanged, true);
            app.TankSpecialistCheckBox.Visible = 'off';
            app.TankSpecialistCheckBox.Text = 'Tank Specialist';
            app.SupportSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.SupportSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @SupportSpecialistCheckBoxValueChanged, true);
            app.SupportSpecialistCheckBox.Visible = 'off';
            app.SupportSpecialistCheckBox.Text = 'Support Specialist';
            app.MasterMarksmanCheckBox = uicheckbox(app.UIFigure);
            app.MasterMarksmanCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterMarksmanCheckBoxValueChanged, true);
            app.MasterMarksmanCheckBox.Visible = 'off';
            app.MasterMarksmanCheckBox.Text = 'Master Marksman';
            app.MarksmanSpecialistCheckBox = uicheckbox(app.UIFigure);
            app.MarksmanSpecialistCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarksmanSpecialistCheckBoxValueChanged, true);
            app.MarksmanSpecialistCheckBox.Visible = 'off';
            app.MarksmanSpecialistCheckBox.Text = 'Marksman Specialist';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'List Champions';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Champions'};
            app.UITable.RowName = {};
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.UITable.Visible = 'off';
            app.ChampionDropDownLabel = uilabel(app.UIFigure);
            app.ChampionDropDownLabel.Text = 'Champion';
            app.ChampionNameDropDown = uidropdown(app.UIFigure);
            app.ChampionNameDropDown.Items = {'Aatrox', 'Ahri', 'Akali', 'Akshan', 'Alistar', 'Ambessa', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Aurora', 'Azir', 'Bard', 'BelVeth', 'Blitzcrank', 'Brand', 'Braum', 'Briar', 'Caitlyn', 'Camille', 'Cassiopeia', 'ChoGath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Gwen', 'Hecarim', 'Heimerdinger', 'Hwei', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'KSante', 'KaiSa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'KhaZix', 'Kindred', 'Kled', 'KogMaw', 'LeBlanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'Mel', 'Milio', 'MissFortune', 'Mordekaiser', 'Morgana', 'Naafiri', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nilah', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'RenataGlasc', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Smolder', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'VelKoz', 'Vex', 'Vi', 'Viego', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'Wukong', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Zeri', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionNameDropDown.Editable = 'on';
            app.ChampionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ChampionNameDropDownValueChanged, true);
            app.ChampionNameDropDown.Placeholder = 'Aatrox';
            app.ChampionNameDropDown.Value = 'Ahri';
            app.ChallengeDropDownLabel = uilabel(app.UIFigure);
            app.ChallengeDropDownLabel.Text = 'Challenge';
            app.ChallengeDropDown = uidropdown(app.UIFigure);
            app.ChallengeDropDown.Items = {'Protean Override', 'Perfectionist', 'Same Penta, Different Champ', 'Invincible', 'All Random, All Champions', 'Need A Bigger Closet'};
            app.ChallengeDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ChallengeDropDown.Value = '1';
            app.PositionFilterDropDownLabel = uilabel(app.UIFigure);
            app.PositionFilterDropDownLabel.Text = 'Position Filter';
            app.PositionFilterDropDown = uidropdown(app.UIFigure);
            app.PositionFilterDropDown.Items = {'', 'top', 'mid', 'jungle', 'bot', 'support'};
            app.PositionFilterDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.PositionFilterDropDown.Editable = 'on';
            app.PositionFilterDropDown.Value = '1';
            app.NeedABiggerClosetCheckBox = uicheckbox(app.UIFigure);
            app.NeedABiggerClosetCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeedABiggerClosetCheckBoxValueChanged, true);
            app.NeedABiggerClosetCheckBox.Tooltip = {'Collect 5 skins on different champions'};
            app.NeedABiggerClosetCheckBox.Text = 'Need A Bigger Closet';
            app.MasterYourselfCheckBox = uicheckbox(app.UIFigure);
            app.MasterYourselfCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterYourselfCheckBoxValueChanged, true);
            app.MasterYourselfCheckBox.Tooltip = {'Earn Mastery 5 on different champions'};
            app.MasterYourselfCheckBox.Text = 'Master Yourself';
            app.MasterTheEnemyCheckBox = uicheckbox(app.UIFigure);
            app.MasterTheEnemyCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasterTheEnemyCheckBoxValueChanged, true);
            app.MasterTheEnemyCheckBox.Tooltip = {'Earn Mastery 10 on different champions'};
            app.MasterTheEnemyCheckBox.Text = 'Master The Enemy';
            app.WellRoundedTravelerCheckBox = uicheckbox(app.UIFigure);
            app.WellRoundedTravelerCheckBox.ValueChangedFcn = createCallbackFcn(app, @WellRoundedTravelerCheckBoxValueChanged, true);
            app.WellRoundedTravelerCheckBox.Tooltip = {'Achieve milestone 15 or greater on an Eternal'};
            app.WellRoundedTravelerCheckBox.Text = 'Well-Rounded Traveler';
            app.RekindleTheOldFurnaceCheckBox = uicheckbox(app.UIFigure);
            app.RekindleTheOldFurnaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @RekindleTheOldFurnaceCheckBoxValueChanged, true);
            app.RekindleTheOldFurnaceCheckBox.Text = 'Rekindle The Old Furnace';
            app.OtherconditionalChallengesLabel = uilabel(app.UIFigure);
            app.OtherconditionalChallengesLabel.Text = 'Other conditional Challenges:';
            app.NowhereToHideCheckBox = uicheckbox(app.UIFigure);
            app.NowhereToHideCheckBox.ValueChangedFcn = createCallbackFcn(app, @NowhereToHideCheckBoxValueChanged, true);
            app.NowhereToHideCheckBox.Text = 'Nowhere To Hide';
            app.ItHasUltimateIntheNameCheckBox = uicheckbox(app.UIFigure);
            app.ItHasUltimateIntheNameCheckBox.ValueChangedFcn = createCallbackFcn(app, @ItHasUltimateIntheNameCheckBoxValueChanged, true);
            app.ItHasUltimateIntheNameCheckBox.Tooltip = {'Win with a 5-stack with 3+ champs with a large AOE ult'};
            app.ItHasUltimateIntheNameCheckBox.Text = 'It Has Ultimate In the Name';
            app.WeProtecCheckBox = uicheckbox(app.UIFigure);
            app.WeProtecCheckBox.ValueChangedFcn = createCallbackFcn(app, @WeProtecCheckBoxValueChanged, true);
            app.WeProtecCheckBox.Tooltip = {'Win with a 5-stack of 3+ champs with heals or shields'};
            app.WeProtecCheckBox.Text = 'We Protec';
            app.TheyJustDontDieCheckBox = uicheckbox(app.UIFigure);
            app.TheyJustDontDieCheckBox.ValueChangedFcn = createCallbackFcn(app, @TheyJustDontDieCheckBoxValueChanged, true);
            app.TheyJustDontDieCheckBox.Tooltip = {'Win with a 5-stack of 3+ champions that defy death'};
            app.TheyJustDontDieCheckBox.Text = 'They Just Don''t Die';
            app.WheredTheyGoCheckBox = uicheckbox(app.UIFigure);
            app.WheredTheyGoCheckBox.ValueChangedFcn = createCallbackFcn(app, @WheredTheyGoCheckBoxValueChanged, true);
            app.WheredTheyGoCheckBox.Tooltip = {'Win with a 5-stack with 3+ champs with stealth'};
            app.WheredTheyGoCheckBox.Text = 'Where''d They Go?';
            app.WereGoodOverHereCheckBox = uicheckbox(app.UIFigure);
            app.WereGoodOverHereCheckBox.ValueChangedFcn = createCallbackFcn(app, @WereGoodOverHereCheckBoxValueChanged, true);
            app.WereGoodOverHereCheckBox.Tooltip = {'Win with a 5-stack with 3+ poke champs'};
            app.WereGoodOverHereCheckBox.Text = 'We''re Good Over Here';
            app.SummonersontheRiftCheckBox = uicheckbox(app.UIFigure);
            app.SummonersontheRiftCheckBox.ValueChangedFcn = createCallbackFcn(app, @SummonersontheRiftCheckBoxValueChanged, true);
            app.SummonersontheRiftCheckBox.Tooltip = {'Win with a 5-stack of champs with a summon or pet'};
            app.SummonersontheRiftCheckBox.Text = 'Summoners on the Rift';
            app.GetOverHereCheckBox = uicheckbox(app.UIFigure);
            app.GetOverHereCheckBox.ValueChangedFcn = createCallbackFcn(app, @GetOverHereCheckBoxValueChanged, true);
            app.GetOverHereCheckBox.Tooltip = {'Win with a 5-stack with 3+ champs with displacements'};
            app.GetOverHereCheckBox.Text = 'Get Over Here';
            app.ItsaTrapCheckBox = uicheckbox(app.UIFigure);
            app.ItsaTrapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ItsaTrapCheckBoxValueChanged, true);
            app.ItsaTrapCheckBox.Tooltip = {'Win with a 5-stack of 3+ champs with traps'};
            app.ItsaTrapCheckBox.Text = 'It''s a Trap!';
            app.ImHelpingCheckBox = uicheckbox(app.UIFigure);
            app.ImHelpingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImHelpingCheckBoxValueChanged, true);
            app.ImHelpingCheckBox.Tooltip = {'Win with a 5-stack of champs with 3+ champs with terrain creation'};
            app.ImHelpingCheckBox.Text = 'I''m Helping';
            app.RegionsofRuneterraLabel = uilabel(app.UIFigure);
            app.RegionsofRuneterraLabel.Text = 'Regions of Runeterra:';
            app.FiveUnderFiveCheckBox = uicheckbox(app.UIFigure);
            app.FiveUnderFiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiveUnderFiveCheckBoxValueChanged, true);
            app.FiveUnderFiveCheckBox.Tooltip = {'Win with a 5-stack of champs from Bandle City'};
            app.FiveUnderFiveCheckBox.Text = '5 Under 5''';
            app.JackofAllChampsCheckBox = uicheckbox(app.UIFigure);
            app.JackofAllChampsCheckBox.ValueChangedFcn = createCallbackFcn(app, @JackofAllChampsCheckBoxValueChanged, true);
            app.JackofAllChampsCheckBox.Tooltip = {'Win games with different champions'};
            app.JackofAllChampsCheckBox.Text = 'Jack of All Champs';
            app.SpiceofLifeCheckBox = uicheckbox(app.UIFigure);
            app.SpiceofLifeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpiceofLifeCheckBoxValueChanged, true);
            app.SpiceofLifeCheckBox.Text = 'Spice of Life';
            app.InvisibleCheckBox = uicheckbox(app.UIFigure);
            app.InvisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvisibleCheckBoxValueChanged, true);
            app.InvisibleCheckBox.Visible = 'off';
            app.InvisibleCheckBox.Tooltip = {'Obtain assassin champions'};
            app.InvisibleCheckBox.Text = 'Invisible';
            app.IndomitableCheckBox = uicheckbox(app.UIFigure);
            app.IndomitableCheckBox.ValueChangedFcn = createCallbackFcn(app, @IndomitableCheckBoxValueChanged, true);
            app.IndomitableCheckBox.Visible = 'off';
            app.IndomitableCheckBox.Tooltip = {'Obtain fighter champions'};
            app.IndomitableCheckBox.Text = 'Indomitable';
            app.IncandescentCheckBox = uicheckbox(app.UIFigure);
            app.IncandescentCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncandescentCheckBoxValueChanged, true);
            app.IncandescentCheckBox.Visible = 'off';
            app.IncandescentCheckBox.Tooltip = {'Obtain mage champions'};
            app.IncandescentCheckBox.Text = 'Incandescent';
            app.IngeniousCheckBox = uicheckbox(app.UIFigure);
            app.IngeniousCheckBox.ValueChangedFcn = createCallbackFcn(app, @IngeniousCheckBoxValueChanged, true);
            app.IngeniousCheckBox.Visible = 'off';
            app.IngeniousCheckBox.Tooltip = {'Obtain marksman champions'};
            app.IngeniousCheckBox.Text = 'Ingenious';
            app.InspiringCheckBox = uicheckbox(app.UIFigure);
            app.InspiringCheckBox.ValueChangedFcn = createCallbackFcn(app, @InspiringCheckBoxValueChanged, true);
            app.InspiringCheckBox.Visible = 'off';
            app.InspiringCheckBox.Tooltip = {'Obtain support champions'};
            app.InspiringCheckBox.Text = 'Inspiring';
            app.InvulnerableCheckBox = uicheckbox(app.UIFigure);
            app.InvulnerableCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvulnerableCheckBoxValueChanged, true);
            app.InvulnerableCheckBox.Visible = 'off';
            app.InvulnerableCheckBox.Tooltip = {'Obtain tank champions'};
            app.InvulnerableCheckBox.Text = 'Invulnerable';
            app.AllHandsonDeckCheckBox = uicheckbox(app.UIFigure);
            app.AllHandsonDeckCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllHandsonDeckCheckBoxValueChanged, true);
            app.AllHandsonDeckCheckBox.Tooltip = {'Win with a 5-stack of champs from Bilgewater'};
            app.AllHandsonDeckCheckBox.Text = 'All Hands on Deck';
            app.ForDemaciaCheckBox = uicheckbox(app.UIFigure);
            app.ForDemaciaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForDemaciaCheckBoxValueChanged, true);
            app.ForDemaciaCheckBox.Tooltip = {'Win with a 5-stack of champs from Demacia'};
            app.ForDemaciaCheckBox.Text = 'FOR DEMACIA';
            app.IceIceBabyCheckBox = uicheckbox(app.UIFigure);
            app.IceIceBabyCheckBox.ValueChangedFcn = createCallbackFcn(app, @IceIceBabyCheckBoxValueChanged, true);
            app.IceIceBabyCheckBox.Tooltip = {'Win with a 5-stack of champs from the Freljord'};
            app.IceIceBabyCheckBox.Text = 'Ice, Ice, Baby';
            app.EverybodywasWujuFightingCheckBox = uicheckbox(app.UIFigure);
            app.EverybodywasWujuFightingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EverybodywasWujuFightingCheckBoxValueChanged, true);
            app.EverybodywasWujuFightingCheckBox.Tooltip = {'Win with a 5-stack of champs from Ionia'};
            app.EverybodywasWujuFightingCheckBox.Text = 'Everybody was Wuju Fighting';
            app.ElementalMyDearWatsonCheckBox = uicheckbox(app.UIFigure);
            app.ElementalMyDearWatsonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElementalMyDearWatsonCheckBoxValueChanged, true);
            app.ElementalMyDearWatsonCheckBox.Tooltip = {'Win with a 5-stack of champions from Ixtal'};
            app.ElementalMyDearWatsonCheckBox.Text = 'Elemental, My Dear Watson';
            app.StrengthAboveAllCheckBox = uicheckbox(app.UIFigure);
            app.StrengthAboveAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @StrengthAboveAllCheckBoxValueChanged, true);
            app.StrengthAboveAllCheckBox.Tooltip = {'Win with a 5-stack of champs from Noxus'};
            app.StrengthAboveAllCheckBox.Text = 'Strength Above All';
            app.CalculatedCheckBox = uicheckbox(app.UIFigure);
            app.CalculatedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatedCheckBoxValueChanged, true);
            app.CalculatedCheckBox.Tooltip = {'Win with a 5-stack of champs from Piltover'};
            app.CalculatedCheckBox.Text = 'Calculated';
            app.SpookyScarySkeletonsCheckBox = uicheckbox(app.UIFigure);
            app.SpookyScarySkeletonsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpookyScarySkeletonsCheckBoxValueChanged, true);
            app.SpookyScarySkeletonsCheckBox.Tooltip = {'Win with a 5-stack of champs from the Shadow Isles'};
            app.SpookyScarySkeletonsCheckBox.Text = 'Spooky Scary Skeletons';
            app.TheSunDiscNeverSetsCheckBox = uicheckbox(app.UIFigure);
            app.TheSunDiscNeverSetsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TheSunDiscNeverSetsCheckBoxValueChanged, true);
            app.TheSunDiscNeverSetsCheckBox.Tooltip = {'Win with a 5-stack of champs from Shurima'};
            app.TheSunDiscNeverSetsCheckBox.Text = 'The Sun Disc Never Sets';
            app.PeakPerformanceCheckBox = uicheckbox(app.UIFigure);
            app.PeakPerformanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeakPerformanceCheckBoxValueChanged, true);
            app.PeakPerformanceCheckBox.Tooltip = {'Win with a 5-stack of champs from Targon'};
            app.PeakPerformanceCheckBox.Text = 'Peak Performance';
            app.InhumanScreechingSoundsCheckBox = uicheckbox(app.UIFigure);
            app.InhumanScreechingSoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InhumanScreechingSoundsCheckBoxValueChanged, true);
            app.InhumanScreechingSoundsCheckBox.Tooltip = {'Win with a 5-stack of champs from the Void'};
            app.InhumanScreechingSoundsCheckBox.Text = '(Inhuman Screeching Sounds)';
            app.ChemtechComradesCheckBox = uicheckbox(app.UIFigure);
            app.ChemtechComradesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChemtechComradesCheckBoxValueChanged, true);
            app.ChemtechComradesCheckBox.Tooltip = {'Win with a 5-stack of champs from Zaun'};
            app.ChemtechComradesCheckBox.Text = 'Chemtech Comrades';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = challengeTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end